classdef GraficasCalidad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        FallostotalesLabel            matlab.ui.control.Label
        NiveldedificultadButtonGroup  matlab.ui.container.ButtonGroup
        Nivel4                        matlab.ui.control.RadioButton
        Nivel3                        matlab.ui.control.RadioButton
        Nivel2                        matlab.ui.control.RadioButton
        Nivel1                        matlab.ui.control.RadioButton
        GridLayout3                   matlab.ui.container.GridLayout
        ResultadosButton              matlab.ui.control.Button
        GridLayout2                   matlab.ui.container.GridLayout
        IniciarmovimientoButton       matlab.ui.control.Button
        AMuneca                       matlab.ui.control.UIAxes
        FHombro                       matlab.ui.control.UIAxes
        AHombro                       matlab.ui.control.UIAxes
        SCodo                         matlab.ui.control.UIAxes
        FMuneca                       matlab.ui.control.UIAxes
        FCodo                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        rango = 9; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
           
            %tiempo de duracion del movimiento
           maxim= 4;
           minim= 1;
           duration = minim+(maxim-minim)*rand(1);


            % value = app.TiempoTotal.Value - duration;
      [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, duration,'cl');
      [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, duration,'cl');  
      [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, duration,'cl');
      [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, duration,'cl');
      [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration,'cl');
      [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1,duration,'cl');
      
      reset(app.FMuneca),reset(app.SCodo),reset(app.AHombro),reset(app.FHombro),reset(app.AMuneca),reset(app.FCodo)
     
      %HA
       [mSUP, mINF]=  RangosCalidad ( app, [10,14,19,26,31,33,30,26,21,16,10], duration, app.rango);
       rHAS = mSUP;
       rHAI = mINF; 
     
      %MA
      [mSUP, mINF]=  RangosCalidad ( app, [5,6,10,12,15,16,15,12,9,6,5], duration, app.rango);
       rMAS = mSUP;
       rMAI = mINF; 
      %CF
      [mSUP, mINF]=  RangosCalidad ( app, [67,70,89,108,115,120,115,107,83,72,67], duration, app.rango);
       rCFS = mSUP;
       rCFI = mINF;
      %HF
      [mSUP, mINF]=  RangosCalidad ( app, [40,42,45,50,56,60,56,50,45,42,40], duration, app.rango);
       rHFS = mSUP;
       rHFI = mINF;
      %MF
      [mSUP, mINF]=  RangosCalidad ( app, [27,25,23,20,21,20,22,20,23,25,27], duration, app.rango);
       rMFS = mSUP;
       rMFI = mINF;
      %SC
      [mSUP, mINF]=  RangosCalidad ( app, [25,28,35,42,52,51,54,25,17,10,25], duration, app.rango);
       rCSS = mSUP;
       rCSI = mINF;
       
 %Pintamos los rangos
       plot(app.FHombro,xHFF,rHFS,'--r'), hold (app.FHombro),
       plot(app.FHombro,xHFF,rHFI,'--r'),
       plot(app.AHombro,xHAF,rHAS,'--r'), hold (app.AHombro),
       plot(app.AHombro,xHAF,rHAI,'--r'),
       plot(app.SCodo,xCSF,rCSS,'--r'), hold (app.SCodo),
       plot(app.SCodo,xCSF,rCSI,'--r'),
       plot(app.FMuneca,xMFF,rMFS,'--r'), hold (app.FMuneca),
       plot(app.FMuneca,xMFF,rMFI,'--r'),
       plot(app.AMuneca,xMAF,rMAS,'--r'), hold (app.AMuneca),
       plot(app.AMuneca,xMAF,rMAI,'--r'),
       plot(app.FCodo,xCFF,rCFS,'--r'), hold (app.FCodo),
       plot(app.FCodo,xCFF,rCFI,'--r'),


       maxsize=max([length(yHAF),length(yHFF),length(yMAF),length(yMFF),length(yCFF),length(yCSF)]);
    i=1;
       while i<maxsize
        if i<length(yHFF)
            plot(app.FHombro,xHFF(1:i),yHFF(1:i))
        end
        if i<length(yHAF)
            plot(app.AHombro,xHAF(1:i),yHAF(1:i))
        end
        if i<length(yCFF)
            plot(app.FCodo,xCFF(1:i),yCFF(1:i))
        end
        if i<length(yCSF)
            plot(app.SCodo,xCSF(1:i),yCSF(1:i))
        end
        if i<length(yMFF)
            plot(app.FMuneca,xMFF(1:i),yMFF(1:i))
        end
        if i<length(yMAF)
            plot(app.AMuneca,xMAF(1:i),yMAF(1:i))
        end
        i=i+30;
        drawnow
        pause(0.01)
       end
%Comparamos el eje y con los rangos para ver que no se ha salido
    j = 1;
    fHF = 0;
    fHA = 0;
    fCF = 0;
    fCS = 0;
    fMF = 0;
    fMA = 0;
    
        while j < maxsize
        if j<length(yHFF)
            if yHFF(j) < rHFS(j) %Comparamos con rango superior
                if yHFF(j) > rHFI(j) %Comparamos con rango inferior
                   fHF = fHF+0 ;%no se suma fallo
                else
                    fHF = fHF+1;
                end
            else 
                fHF = fHF+1; %Se suma fallo
            end
               
        end
        if j<length(yHAF)
             if yHAF(j) < rHAS(j) %Comparamos con rango superior
                if yHAF(j) > rHAI(j) %Comparamos con rango inferior
                   fHA = fHA+0 ;%no se suma fallo
                else
                    fHA = fHA+1;
                end
            else 
                fHA = fHA+1; %Se suma fallo
             end
        end
        if j<length(yCFF)
             if yCFF(j) < rCFS(j) %Comparamos con rango superior
                if yCFF(j) > rCFI(j) %Comparamos con rango inferior
                   fCF = fCF+0 ;%no se suma fallo
                else
                    fCF = fCF+1;
                end
            else 
                fCF = fCF+1; %Se suma fallo
             end
        end
        if j<length(yCSF)
             if yCSF(j) < rCSS(j) %Comparamos con rango superior
                if yCSF(j) > rCSI(j) %Comparamos con rango inferior
                   fCS = fCS+0 ;%no se suma fallo
                else
                    fCS = fCS+1;
                end
            else 
                fCS = fCS+1; %Se suma fallo
             end
        end
        if j<length(yMFF)
             if yMFF(j) < rMFS(j) %Comparamos con rango superior
                if yMFF(j) > rMFI(j) %Comparamos con rango inferior
                   fMF = fMF+0 ;%no se suma fallo
                else
                    fMF = fMF+1;
                end
            else 
                fMF = fMF+1; %Se suma fallo
             end
        end
        if j<length(yMAF)
             if yMAF(j) < rMAS(j) %Comparamos con rango superior
                if yMAF(j) > rMAI(j) %Comparamos con rango inferior
                   fMA = fMA+0 ;%no se suma fallo
                else
                    fMA = fMA+1;
                end
            else 
                fMA = fMA+1; %Se suma fallo
             end
        end
        j=j+1;
        
        end
   
 %NUMERO DE FALLOS TOTALES E INDIVIDUALES
    fTotal = fMA + fMF + fCS + fCF + fHA + fHF;
    value = 'Los fallos totales son: ' + string(fTotal);
    set(app.FallostotalesLabel, 'Text', value);


%      T = table (fMA,fMF,fCS,fCF,fHA,fHF,fTotal);
%                 L = {'Fallos en la Abduccion de la Muñeca',
%                     'Fallos en la Flexion de la Muñeca',
%                     'Fallos en la Supinacion del Codo',
%                     'Fallos en la Flexion del Codo', 
%                     'Fallos en la Abduccion del Hombro', 
%                     'Fallos en la Flexion del Hombro',
%                 'Fallos Totales'            
%                 };
%                 C = table2cell(T);
%                 %Concateno en la matriz cell la legenda de cada columna
%                 A = [L;C];
%                 xlswrite('ProgresoCalidad.xlsx',A,'xlswrite','A2');

 T = table (fMA , fMF , fCS , fCF , fHA ,fHF);
                L = {'fMA' , 'fMF' , 'fCS' , 'fCF' , 'fHA' ,'fHF'};
                C = table2cell(T);
                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                xlswrite('ProgresoCalidad.xlsx',A,'xlswrite','A2');


        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Calidad;
            closereq ();
        end

        % Selection changed function: NiveldedificultadButtonGroup
        function NiveldedificultadButtonGroupSelectionChanged(app, event)
            selectedButton = app.NiveldedificultadButtonGroup.SelectedObject;
            
            if selectedButton == app.Nivel1 
                app.rango = 9;
            elseif selectedButton == app.Nivel2 
                app.rango = 7;
            elseif selectedButton == app.Nivel3 
                app.rango = 5;
            elseif selectedButton == app.Nivel4 
                app.rango = 3;
            else
                app.rango=9;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FCodo = uiaxes(app.GridLayout);
            title(app.FCodo, {'Flexión Codo'; ''})
            xlabel(app.FCodo, 'X')
            ylabel(app.FCodo, 'Y')
            zlabel(app.FCodo, 'Z')
            app.FMuneca = uiaxes(app.GridLayout);
            title(app.FMuneca, 'Flexión Muñeca')
            xlabel(app.FMuneca, 'X')
            ylabel(app.FMuneca, 'Y')
            zlabel(app.FMuneca, 'Z')
            app.SCodo = uiaxes(app.GridLayout);
            title(app.SCodo, {'Supinación Codo'; ''})
            xlabel(app.SCodo, 'X')
            ylabel(app.SCodo, 'Y')
            zlabel(app.SCodo, 'Z')
            app.AHombro = uiaxes(app.GridLayout);
            title(app.AHombro, {'Abduccióm Hombro'; ''})
            xlabel(app.AHombro, 'X')
            ylabel(app.AHombro, 'Y')
            zlabel(app.AHombro, 'Z')
            app.FHombro = uiaxes(app.GridLayout);
            title(app.FHombro, {'Flexión Hombro'; ''})
            xlabel(app.FHombro, 'X')
            ylabel(app.FHombro, 'Y')
            zlabel(app.FHombro, 'Z')
            app.AMuneca = uiaxes(app.GridLayout);
            title(app.AMuneca, {'Abducción Muñeca'; ''})
            xlabel(app.AMuneca, 'X')
            ylabel(app.AMuneca, 'Y')
            zlabel(app.AMuneca, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.IniciarmovimientoButton = uibutton(app.GridLayout2, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = {'Iniciar movimiento'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ResultadosButton = uibutton(app.GridLayout3, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = {'Resultados'; ''};
            app.NiveldedificultadButtonGroup = uibuttongroup(app.GridLayout);
            app.NiveldedificultadButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NiveldedificultadButtonGroupSelectionChanged, true);
            app.NiveldedificultadButtonGroup.Title = 'Nivel de dificultad';
            app.Nivel1 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel1.Text = '1';
            app.Nivel1.Value = true;
            app.Nivel2 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel2.Text = '2';
            app.Nivel3 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel3.Text = '3';
            app.Nivel4 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel4.Text = '4';
            app.FallostotalesLabel = uilabel(app.GridLayout);
            app.FallostotalesLabel.Text = 'Fallos totales : ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficasCalidad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end